classdef AddRecord < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AddRecordView         matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        CancelarButton        matlab.ui.control.Button
        AplicarButton         matlab.ui.control.Button
        NombreEditFieldLabel  matlab.ui.control.Label
        NombreEditField       matlab.ui.control.EditField
        ArchivoLabel          matlab.ui.control.Label
        NoFileButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        appcallback
        fileroot = '';
        ext = ''
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, appcallback)
            app.appcallback = appcallback;
        end

        % Button pushed function: CancelarButton
        function CancelarButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: AplicarButton
        function AplicarButtonPushed(app, event)
            name = app.NombreEditField.Value;
            
            if ~isequal(name, '') && ~isequal(app.fileroot, '')
                callbackAddRec(app.appcallback, name, app.fileroot, app.ext)
                delete(app)
            else
                warndlg('Llene todos los campos','Alerta');
            end
            
        end

        % Button pushed function: NoFileButton
        function NoFileButtonPushed(app, event)
            [file,path, ix] = uigetfile({'*.txt'; '*.wav;*.mp3'; '*.csv'; '*.json'},'Selecciona archivo', 'C:\Users\Yeyian\Documents\SignalLab\');
            
            if isequal(file, 0)
                app.fileroot = '';
                app.ext = '';
                app.NoFileButton.Text = 'No File';
            else
                app.fileroot = strcat(path, file);
                app.ext = ix;
                app.NoFileButton.Text = file;
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AddRecordView = uifigure('Visible', 'off');
            app.AddRecordView.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.AddRecordView);
            app.CancelarButton = uibutton(app.GridLayout, 'push');
            app.CancelarButton.ButtonPushedFcn = createCallbackFcn(app, @CancelarButtonPushed, true);
            app.CancelarButton.Text = 'Cancelar';
            app.AplicarButton = uibutton(app.GridLayout, 'push');
            app.AplicarButton.ButtonPushedFcn = createCallbackFcn(app, @AplicarButtonPushed, true);
            app.AplicarButton.Text = 'Aplicar';
            app.NombreEditFieldLabel = uilabel(app.GridLayout);
            app.NombreEditFieldLabel.Text = 'Nombre';
            app.NombreEditField = uieditfield(app.GridLayout, 'text');
            app.ArchivoLabel = uilabel(app.GridLayout);
            app.ArchivoLabel.Text = 'Archivo';
            app.NoFileButton = uibutton(app.GridLayout, 'push');
            app.NoFileButton.ButtonPushedFcn = createCallbackFcn(app, @NoFileButtonPushed, true);
            app.NoFileButton.Text = 'No File';
            % Show the figure after all components are created
            app.AddRecordView.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AddRecord(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AddRecordView)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AddRecordView)
        end
    end
end