classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AppView                         matlab.ui.Figure
        EntornoMenu                     matlab.ui.container.Menu
        NuevoEntornoMenu                matlab.ui.container.Menu
        AbrirEntornoMenu                matlab.ui.container.Menu
        GuardarEntornoMenu              matlab.ui.container.Menu
        GuardarEntornoComoMenu          matlab.ui.container.Menu
        RegistrosMenu                   matlab.ui.container.Menu
        AgregarRegistroMenu             matlab.ui.container.Menu
        BorrarRegistroMenu              matlab.ui.container.Menu
        GuardarComoAudioMenu            matlab.ui.container.Menu
        AnalisisMenu                    matlab.ui.container.Menu
        AnalisisdeFourierMenu           matlab.ui.container.Menu
        MuestraDiscretaMenu             matlab.ui.container.Menu
        MuestraDigitalMenu              matlab.ui.container.Menu
        CrearReporteMenu                matlab.ui.container.Menu
        FiltrosMenu                     matlab.ui.container.Menu
        LowpassMenu                     matlab.ui.container.Menu
        HighpassMenu                    matlab.ui.container.Menu
        BandpassMenu                    matlab.ui.container.Menu
        BandstopMenu                    matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        SealyAnalisisTab                matlab.ui.container.Tab
        GridLayout13                    matlab.ui.container.GridLayout
        ModificarSealPanel              matlab.ui.container.Panel
        GridLayout14_5                  matlab.ui.container.GridLayout
        MultiplicadordeAmplitudEditFieldLabel  matlab.ui.control.Label
        MultiplicadordeAmplitudEditField  matlab.ui.control.NumericEditField
        ResetAmplifyBtn                 matlab.ui.control.Button
        ApplyAmplifyBtn                 matlab.ui.control.Button
        MultiplicadordefrecuenciaEditFieldLabel  matlab.ui.control.Label
        MultiplicadordefrecuenciaEditField  matlab.ui.control.NumericEditField
        LimitedeCorteInferioryEditFieldLabel  matlab.ui.control.Label
        LimitedeCorteInferioryEditField  matlab.ui.control.NumericEditField
        LimitedeCorteIzquierdoxEditFieldLabel  matlab.ui.control.Label
        LimitedeCorteIzquierdoxEditField  matlab.ui.control.NumericEditField
        LimitedeCorteDerechoxEditFieldLabel  matlab.ui.control.Label
        LimitedeCorteDerechoxEditField  matlab.ui.control.NumericEditField
        LimitedeCorteSuperioryEditFieldLabel  matlab.ui.control.Label
        LimitedeCorteSuperioryEditField  matlab.ui.control.NumericEditField
        AnalisisdeFourierPanel          matlab.ui.container.Panel
        GridLayout14_6                  matlab.ui.container.GridLayout
        ResetFourierBtn                 matlab.ui.control.Button
        ApplyFourierBtn                 matlab.ui.control.Button
        AplicaranalisisdeFourierCheckBox  matlab.ui.control.CheckBox
        AnalisisDiscretoPanel           matlab.ui.container.Panel
        GridLayout14_7                  matlab.ui.container.GridLayout
        FrecuenciadeMuestreoEditFieldLabel  matlab.ui.control.Label
        FrecuenciadeMuestreoEditField   matlab.ui.control.NumericEditField
        RestablecerButton_13            matlab.ui.control.Button
        AplicarButton_13                matlab.ui.control.Button
        AplicaranalisisDiscretoCheckBox  matlab.ui.control.CheckBox
        InformacindelasealPanel         matlab.ui.container.Panel
        GridLayout14_9                  matlab.ui.container.GridLayout
        NombredeLaSealLabel             matlab.ui.control.Label
        SignalNameLabel                 matlab.ui.control.Label
        FrecuenciadeMuestreoLabel       matlab.ui.control.Label
        SignalFrecuencyLabel            matlab.ui.control.Label
        DuracinLabel                    matlab.ui.control.Label
        SignalDurationLabel             matlab.ui.control.Label
        ReproducirButton                matlab.ui.control.Button
        GraficasTab                     matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        MuestraOriginalPanel            matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        UIAxesOriginalSignal            matlab.ui.control.UIAxes
        UIAxesOriginalFourier           matlab.ui.control.UIAxes
        UIAxesOriginalDiscreet          matlab.ui.control.UIAxes
        OpcionesSealPanel               matlab.ui.container.Panel
        GridLayout9                     matlab.ui.container.GridLayout
        LimiteSuperioryEditFieldLabel   matlab.ui.control.Label
        LimiteSuperioryEditField        matlab.ui.control.NumericEditField
        LimiteInferioryEditFieldLabel   matlab.ui.control.Label
        LimiteInferioryEditField        matlab.ui.control.NumericEditField
        LimiteIzquierdoxEditFieldLabel  matlab.ui.control.Label
        LimiteIzquierdoxEditField       matlab.ui.control.NumericEditField
        LimiteDerechoxEditFieldLabel    matlab.ui.control.Label
        LimiteDerechoxEditField         matlab.ui.control.NumericEditField
        XGridCheckBox                   matlab.ui.control.CheckBox
        YGridCheckBox                   matlab.ui.control.CheckBox
        AplicarButton_5                 matlab.ui.control.Button
        RestablecerButton_5             matlab.ui.control.Button
        OpcionesFourierPanel            matlab.ui.container.Panel
        GridLayout9_12                  matlab.ui.container.GridLayout
        LimiteSuperioryEditField_7Label  matlab.ui.control.Label
        LimiteSuperioryEditField_7      matlab.ui.control.NumericEditField
        LimiteInferioryEditField_7Label  matlab.ui.control.Label
        LimiteInferioryEditField_7      matlab.ui.control.NumericEditField
        LimiteIzquierdoxEditField_7Label  matlab.ui.control.Label
        LimiteIzquierdoxEditField_7     matlab.ui.control.NumericEditField
        LimiteDerechoxEditField_7Label  matlab.ui.control.Label
        LimiteDerechoxEditField_7       matlab.ui.control.NumericEditField
        XGridCheckBox_7                 matlab.ui.control.CheckBox
        YGridCheckBox_7                 matlab.ui.control.CheckBox
        AplicarButton_6                 matlab.ui.control.Button
        RestablecerButton_6             matlab.ui.control.Button
        OpcionesMuestraDiscretaPanel    matlab.ui.container.Panel
        GridLayout9_13                  matlab.ui.container.GridLayout
        LimiteSuperioryEditField_8Label  matlab.ui.control.Label
        LimiteSuperioryEditField_8      matlab.ui.control.NumericEditField
        LimiteInferioryEditField_8Label  matlab.ui.control.Label
        LimiteInferioryEditField_8      matlab.ui.control.NumericEditField
        LimiteIzquierdoxEditField_8Label  matlab.ui.control.Label
        LimiteIzquierdoxEditField_8     matlab.ui.control.NumericEditField
        LimiteDerechoxEditField_8Label  matlab.ui.control.Label
        LimiteDerechoxEditField_8       matlab.ui.control.NumericEditField
        XGridCheckBox_8                 matlab.ui.control.CheckBox
        YGridCheckBox_8                 matlab.ui.control.CheckBox
        AplicarButton_7                 matlab.ui.control.Button
        RestablecerButton_7             matlab.ui.control.Button
        MuestraModificadaPanel          matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        UIAxesModifiedDiscreet          matlab.ui.control.UIAxes
        UIAxesModifiedFourier           matlab.ui.control.UIAxes
        UIAxesModifiedSignal            matlab.ui.control.UIAxes
        OpcionesSealPanel_4             matlab.ui.container.Panel
        GridLayout9_14                  matlab.ui.container.GridLayout
        LimiteSuperioryEditField_9Label  matlab.ui.control.Label
        LimiteSuperioryEditField_9      matlab.ui.control.NumericEditField
        LimiteInferioryEditField_9Label  matlab.ui.control.Label
        LimiteInferioryEditField_9      matlab.ui.control.NumericEditField
        LimiteIzquierdoxEditField_9Label  matlab.ui.control.Label
        LimiteIzquierdoxEditField_9     matlab.ui.control.NumericEditField
        LimiteDerechoxEditField_9Label  matlab.ui.control.Label
        LimiteDerechoxEditField_9       matlab.ui.control.NumericEditField
        XGridCheckBox_9                 matlab.ui.control.CheckBox
        YGridCheckBox_9                 matlab.ui.control.CheckBox
        AplicarButton_8                 matlab.ui.control.Button
        RestablecerButton_8             matlab.ui.control.Button
        OpcionesFourierPanel_2          matlab.ui.container.Panel
        GridLayout9_15                  matlab.ui.container.GridLayout
        LimiteSuperioryEditField_10Label  matlab.ui.control.Label
        LimiteSuperioryEditField_10     matlab.ui.control.NumericEditField
        LimiteInferioryEditField_10Label  matlab.ui.control.Label
        LimiteInferioryEditField_10     matlab.ui.control.NumericEditField
        LimiteIzquierdoxEditField_10Label  matlab.ui.control.Label
        LimiteIzquierdoxEditField_10    matlab.ui.control.NumericEditField
        LimiteDerechoxEditField_10Label  matlab.ui.control.Label
        LimiteDerechoxEditField_10      matlab.ui.control.NumericEditField
        XGridCheckBox_10                matlab.ui.control.CheckBox
        YGridCheckBox_10                matlab.ui.control.CheckBox
        AplicarButton_9                 matlab.ui.control.Button
        RestablecerButton_9             matlab.ui.control.Button
        OpcionesMuestraDiscretaPanel_2  matlab.ui.container.Panel
        GridLayout9_16                  matlab.ui.container.GridLayout
        LimiteSuperioryEditField_11Label  matlab.ui.control.Label
        LimiteSuperioryEditField_11     matlab.ui.control.NumericEditField
        LimiteInferioryEditField_11Label  matlab.ui.control.Label
        LimiteInferioryEditField_11     matlab.ui.control.NumericEditField
        LimiteIzquierdoxEditField_11Label  matlab.ui.control.Label
        LimiteIzquierdoxEditField_11    matlab.ui.control.NumericEditField
        LimiteDerechoxEditField_11Label  matlab.ui.control.Label
        LimiteDerechoxEditField_11      matlab.ui.control.NumericEditField
        XGridCheckBox_11                matlab.ui.control.CheckBox
        YGridCheckBox_11                matlab.ui.control.CheckBox
        AplicarButton_10                matlab.ui.control.Button
        RestablecerButton_10            matlab.ui.control.Button
        FiltrosTab_2                    matlab.ui.container.Tab
        GridLayout12                    matlab.ui.container.GridLayout
        LowPassPanel                    matlab.ui.container.Panel
        GridLayout14                    matlab.ui.container.GridLayout
        LimiteInferiorEditFieldLabel    matlab.ui.control.Label
        LimiteInferiorLowPassEditField  matlab.ui.control.NumericEditField
        AplicarLowPassCheckBox          matlab.ui.control.CheckBox
        RestablecerLowPassBtn           matlab.ui.control.Button
        AplicarLowPassBtn               matlab.ui.control.Button
        HighPassPanel                   matlab.ui.container.Panel
        GridLayout14_2                  matlab.ui.container.GridLayout
        LimiteSuperiorEditFieldLabel    matlab.ui.control.Label
        LimiteSuperiorHighPassEditField  matlab.ui.control.NumericEditField
        AplicarHighPassCheckBox         matlab.ui.control.CheckBox
        RestablecerHighPassBtn          matlab.ui.control.Button
        AplicarHighPassBtn              matlab.ui.control.Button
        BandPassPanel                   matlab.ui.container.Panel
        GridLayout14_3                  matlab.ui.container.GridLayout
        LimiteInferiorEditField_2Label  matlab.ui.control.Label
        LimiteInferiorEditField_2       matlab.ui.control.NumericEditField
        AplicarfiltroCheckBox_3         matlab.ui.control.CheckBox
        RestablecerButton_3             matlab.ui.control.Button
        AplicarButton_3                 matlab.ui.control.Button
        LimiteSuperiorEditField_2Label  matlab.ui.control.Label
        LimiteSuperiorEditField_2       matlab.ui.control.NumericEditField
        BandStopPanel                   matlab.ui.container.Panel
        GridLayout14_4                  matlab.ui.container.GridLayout
        LimiteInferiorEditField_3Label  matlab.ui.control.Label
        LimiteInferiorEditField_3       matlab.ui.control.NumericEditField
        AplicarfiltroCheckBox_4         matlab.ui.control.CheckBox
        RestablecerButton_4             matlab.ui.control.Button
        AplicarButton_4                 matlab.ui.control.Button
        LimiteSuperiorEditField_3Label  matlab.ui.control.Label
        LimiteSuperiorEditField_3       matlab.ui.control.NumericEditField
        CrearSealTab                    matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        GrabarAudioPanel                matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        UIAxes7                         matlab.ui.control.UIAxes
        OpcionesGrabacionPanel          matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        CrearRegistroButton             matlab.ui.control.Button
        DuracinEditFieldLabel           matlab.ui.control.Label
        DuracinEditField                matlab.ui.control.NumericEditField
        NombreEditFieldLabel            matlab.ui.control.Label
        NombreEditField                 matlab.ui.control.EditField
        IniciarGrabacinButton           matlab.ui.control.Button
        GrabandoLabel                   matlab.ui.control.Label
        GrabandoLamp                    matlab.ui.control.Lamp
        NombreAutomaticoCheckBox        matlab.ui.control.CheckBox
        SealSinteticaPanel              matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        UIAxes8                         matlab.ui.control.UIAxes
        OpcionesSealPanel_5             matlab.ui.container.Panel
        GridLayout11                    matlab.ui.container.GridLayout
        CrearRegistroButton_2           matlab.ui.control.Button
        TipodesealDropDownLabel         matlab.ui.control.Label
        TipodesealDropDown              matlab.ui.control.DropDown
        FaseEditFieldLabel              matlab.ui.control.Label
        FaseEditField                   matlab.ui.control.NumericEditField
        FrecuenciaEditFieldLabel        matlab.ui.control.Label
        FrecuenciaEditField             matlab.ui.control.NumericEditField
        AmplitudEditFieldLabel          matlab.ui.control.Label
        AmplitudEditField               matlab.ui.control.NumericEditField
        DuracinEditField_2Label         matlab.ui.control.Label
        DuracinEditField_2              matlab.ui.control.NumericEditField
        NombreEditField_2Label          matlab.ui.control.Label
        NombreEditField_2               matlab.ui.control.EditField
        NombreAutomaticoCheckBox_2      matlab.ui.control.CheckBox
        FuncionPersonalizadaCheckBox    matlab.ui.control.CheckBox
        FuncinTextAreaLabel             matlab.ui.control.Label
        FuncinTextArea                  matlab.ui.control.TextArea
        GraficarButton                  matlab.ui.control.Button
        LimpiarButton                   matlab.ui.control.Button
        Operacionescon2SealesTab        matlab.ui.container.Tab
        GridLayout18                    matlab.ui.container.GridLayout
        OpcionesOperacionesPanel        matlab.ui.container.Panel
        GridLayout19                    matlab.ui.container.GridLayout
        Registro1DropDownLabel          matlab.ui.control.Label
        Registro1DropDown               matlab.ui.control.DropDown
        Registro2DropDownLabel          matlab.ui.control.Label
        Registro2DropDown               matlab.ui.control.DropDown
        OperacinDropDownLabel           matlab.ui.control.Label
        OperacinDropDown                matlab.ui.control.DropDown
        AplicarButton_16                matlab.ui.control.Button
        CrearRegistroButton_3           matlab.ui.control.Button
        Who1DropDown                    matlab.ui.control.DropDown
        Who2DropDown                    matlab.ui.control.DropDown
        UIAxes9                         matlab.ui.control.UIAxes
        OpcionesGraficaPanel            matlab.ui.container.Panel
        GridLayout9_17                  matlab.ui.container.GridLayout
        LimiteSuperioryEditField_12Label  matlab.ui.control.Label
        LimiteSuperioryEditField_12     matlab.ui.control.NumericEditField
        LimiteInferioryEditField_12Label  matlab.ui.control.Label
        LimiteInferioryEditField_12     matlab.ui.control.NumericEditField
        LimiteIzquierdoxEditField_12Label  matlab.ui.control.Label
        LimiteIzquierdoxEditField_12    matlab.ui.control.NumericEditField
        LimiteDerechoxEditField_12Label  matlab.ui.control.Label
        LimiteDerechoxEditField_12      matlab.ui.control.NumericEditField
        XGridCheckBox_12                matlab.ui.control.CheckBox
        YGridCheckBox_12                matlab.ui.control.CheckBox
        AplicarButton_15                matlab.ui.control.Button
        RestablecerButton_15            matlab.ui.control.Button
        RegistrosdeSealPanel            matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        AgregarRegistroButton           matlab.ui.control.Button
        BorrarRegistroButton            matlab.ui.control.Button
        ListBox                         matlab.ui.control.ListBox
    end

    
    properties (Access = private)
        Records = SignalRecord('', [0], 0);
        actived = 0;
    end
    
    methods (Access = public)
        %% callbacks
        function callbackAddRec(app, name, file, ext)  
            switch ext
                case 1
                case 2
                    [y, Fs] = audioread(file);
                case 3
                case 4
                otherwise
            end
            
            app.createSignalRecord(name, y, Fs);
            
        end
        
        function callbackFourierAnalisis(app, apply)  
            id = app.actived;
            
            record = app.Records(id).applyFourierAnalisys(apply);
            app.Records(id) = record;
            
            app.AplicaranalisisdeFourierCheckBox.Value = apply;
            
            app.plotAll()
        end
        
        function callbackLowPassFilter(app, apply, lim)  
            id = app.actived;
            
            record = app.Records(id).applyLowPassFilter(apply, lim);
            app.Records(id) = record;
            
            app.AplicarLowPassCheckBox.Value = apply;
            app.LimiteInferiorLowPassEditField.Value = lim;
            
            app.plotAll()
        end
        
        function callbackHighPassFilter(app, apply, lim)  
            id = app.actived;
            
            record = app.Records(id).applyHighPassFilter(apply, lim);
            app.Records(id) = record;
            
            app.AplicarHighPassCheckBox.Value = apply;
            app.LimiteSuperiorHighPassEditField.Value = lim;
            
            app.plotAll()
        end
    
    end
    
    methods (Access = private)
        %% internal functions
        function app = createSignalRecord(app, name, y, Fs)
            record = SignalRecord(name, y, Fs);
            
            app.Records(end+1) = record;
            app.setRecordList();
        end

        %% setters
        function setRecordInfo(app)
            id = app.actived;
            
            frec = string(app.Records(id).originalSignal.signal.Fs);
            duration = string(app.Records(id).originalSignal.duration);
            
            app.SignalNameLabel.Text = app.Records(id).name;
            app.SignalFrecuencyLabel.Text = strcat(frec, 'Hz');
            app.SignalDurationLabel.Text = strcat(duration, 's');
        end
        
        function setAmplificationInfo(app)
            id = app.actived;
            
            [multiplier_A, multiplier_F, begin, finish, max_A, min_A] = app.Records(id).getAmplification();
            
            app.MultiplicadordeAmplitudEditField.Value = multiplier_A;
            app.MultiplicadordefrecuenciaEditField.Value = multiplier_F;
            app.LimitedeCorteInferioryEditField.Value = min_A;
            app.LimitedeCorteSuperioryEditField.Value = max_A;
            app.LimitedeCorteIzquierdoxEditField.Value = begin;
            app.LimitedeCorteDerechoxEditField.Value = finish;
            
        end
        
        function setRecordList(app)
            namelist = {};
            
            [~, rows] = size(app.Records);
            
            for i = 2:rows
                namelist(end + 1) = {app.Records(i).name};
            end
            
            app.ListBox.Items = namelist;
            app.ListBox.ItemsData = 1:(rows-1);
            
            app.Registro1DropDown.Items = namelist;
            app.Registro1DropDown.ItemsData = 1:(rows-1);
            
            app.Registro2DropDown.Items = namelist;
            app.Registro2DropDown.ItemsData = 1:(rows-1);
        end
        
        %% plots
        function plotAll(app)
            app.plotOriginalSignal();
            app.plotOriginalFourier();
            app.plotOriginalDiscreet();
            app.plotModifiedSignal();
            app.plotModifiedFourier();
            app.plotModifiedDiscreet();
            
        end
        
        function plotModified(app)
            app.plotModifiedSignal();
            app.plotModifiedFourier();
            app.plotModifiedDiscreet();
        end
        
        function plotOriginalSignal(app)
            id = app.actived;
            
            [y, t, Fs] = app.Records(id).getOriginalSignal();
            
            plot(app.UIAxesOriginalSignal, t, y);
        end
        
        function plotOriginalFourier(app)
            id = app.actived;
            
            [apply, y, F] = app.Records(id).getOriginalFourier();
            
            plot(app.UIAxesOriginalFourier, F, y);
        end
        
        function plotOriginalDiscreet(app)
            id = app.actived;
            
            [apply, y, t, Fs] = app.Records(id).getOriginalDiscreet();
            
            plot(app.UIAxesOriginalDiscreet, t, y);
        end
        
        function plotModifiedSignal(app)
            id = app.actived;
            
            [y, t, Fs] = app.Records(id).getModifiedSignal();
            
            plot(app.UIAxesModifiedSignal, t, y);
        end
        
        function plotModifiedFourier(app)
            id = app.actived;
            
            [y, F] = app.Records(id).getModifiedFourier();
            
            plot(app.UIAxesModifiedFourier, F, y);
        end
        
        function plotModifiedDiscreet(app)
            id = app.actived;
            
            [y, t, Fs] = app.Records(id).getModifiedDiscreet();
            
            plot(app.UIAxesModifiedDiscreet, t, y);
        end
            
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Menu selected function: LowpassMenu
        function LowpassMenuSelected(app, event)
            id = app.actived;
            low = app.Records(id).filters.low;
            
            apply = low.apply;
            lim = low.lim;
            
            LowPass(app, apply, lim);
        end

        % Menu selected function: HighpassMenu
        function HighpassMenuSelected(app, event)
            id = app.actived;
            high = app.Records(id).filters.high;
            
            apply = high.apply;
            lim = high.lim;
            
            HighPass(app, apply, lim);
        end

        % Menu selected function: AgregarRegistroMenu
        function AgregarRegistroMenuSelected(app, event)
            AddRecord(app);
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            app.actived = value + 1;
            
            app.setRecordInfo();
            app.setAmplificationInfo();
            app.plotAll();
        end

        % Button pushed function: AgregarRegistroButton
        function AgregarRegistroButtonPushed(app, event)
            AddRecord(app);
        end

        % Button pushed function: ApplyAmplifyBtn
        function ApplyAmplifyBtnPushed(app, event)
            id = app.actived;
            
            multiplier_A = app.MultiplicadordeAmplitudEditField.Value;
            multiplier_F = app.MultiplicadordefrecuenciaEditField.Value;
            min_A = app.LimitedeCorteInferioryEditField.Value;
            max_A = app.LimitedeCorteSuperioryEditField.Value;
            begin = app.LimitedeCorteIzquierdoxEditField.Value;
            finish = app.LimitedeCorteDerechoxEditField.Value;
            
            app.Records(id) = app.Records(id).applyAmplify(multiplier_A, multiplier_F, begin, finish, max_A, min_A);
            
            app.plotAll();
        end

        % Menu selected function: AnalisisdeFourierMenu
        function AnalisisdeFourierMenuSelected(app, event)
            id = app.actived;
            
            apply = app.Records(id).originalSignal.fourier.apply;
            
            FourierAnalisys(app, apply);
        end

        % Button pushed function: ResetAmplifyBtn
        function ResetAmplifyBtnButtonPushed(app, event)
            id = app.actived;
            
            record = app.Records(id);
            
            [y, ~, ~] = record.getOriginalSignal();
            
            finish = record.originalSignal.duration;
            max_A = max(y);
            min_A = min(y);
            
            record = record.applyAmplify(1, 1, 0, finish, max_A, min_A);
            
            app.MultiplicadordeAmplitudEditField.Value = 1;
            app.MultiplicadordefrecuenciaEditField.Value = 1;
            app.LimitedeCorteInferioryEditField.Value = min_A;
            app.LimitedeCorteSuperioryEditField.Value = max_A;
            app.LimitedeCorteIzquierdoxEditField.Value = 0;
            app.LimitedeCorteDerechoxEditField.Value = finish;
            
            app.Records(id) = record;
            
            app.plotAll();
        end

        % Button pushed function: ResetFourierBtn
        function ResetFourierBtnButtonPushed(app, event)
            id = app.actived;
            
            record = app.Records(id).applyFourierAnalisys(false);
            app.Records(id) = record;
            
            app.AplicaranalisisdeFourierCheckBox.Value = false;
            
            app.plotAll()
        end

        % Button pushed function: ApplyFourierBtn
        function ApplyFourierBtnButtonPushed(app, event)
            id = app.actived;
            
            apply = app.AplicaranalisisdeFourierCheckBox.Value;
            
            record = app.Records(id).applyFourierAnalisys(apply);
            app.Records(id) = record;
            
            app.plotAll()
        end

        % Menu selected function: BandpassMenu
        function BandpassMenuSelected(app, event)
            id = app.actived;
            band = app.Records(id).filters.band;
            
            apply = band.apply;
            lims = band.lims;
            
            HighPass(app, apply, lims);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AppView = uifigure('Visible', 'off');
            app.AppView.Name = 'Signal Lab';
            app.EntornoMenu = uimenu(app.AppView);
            app.EntornoMenu.Text = 'Entorno';
            app.NuevoEntornoMenu = uimenu(app.EntornoMenu);
            app.NuevoEntornoMenu.Text = 'Nuevo Entorno';
            app.AbrirEntornoMenu = uimenu(app.EntornoMenu);
            app.AbrirEntornoMenu.Text = 'Abrir Entorno';
            app.GuardarEntornoMenu = uimenu(app.EntornoMenu);
            app.GuardarEntornoMenu.Separator = 'on';
            app.GuardarEntornoMenu.Text = 'Guardar Entorno';
            app.GuardarEntornoComoMenu = uimenu(app.EntornoMenu);
            app.GuardarEntornoComoMenu.Text = 'Guardar Entorno Como';
            app.RegistrosMenu = uimenu(app.AppView);
            app.RegistrosMenu.Text = 'Registros';
            app.AgregarRegistroMenu = uimenu(app.RegistrosMenu);
            app.AgregarRegistroMenu.MenuSelectedFcn = createCallbackFcn(app, @AgregarRegistroMenuSelected, true);
            app.AgregarRegistroMenu.Text = 'Agregar Registro';
            app.BorrarRegistroMenu = uimenu(app.RegistrosMenu);
            app.BorrarRegistroMenu.Text = 'Borrar Registro';
            app.GuardarComoAudioMenu = uimenu(app.RegistrosMenu);
            app.GuardarComoAudioMenu.Text = 'Guardar Como Audio';
            app.AnalisisMenu = uimenu(app.AppView);
            app.AnalisisMenu.Text = 'Analisis';
            app.AnalisisdeFourierMenu = uimenu(app.AnalisisMenu);
            app.AnalisisdeFourierMenu.MenuSelectedFcn = createCallbackFcn(app, @AnalisisdeFourierMenuSelected, true);
            app.AnalisisdeFourierMenu.Text = 'Analisis de Fourier';
            app.MuestraDiscretaMenu = uimenu(app.AnalisisMenu);
            app.MuestraDiscretaMenu.Text = 'Muestra Discreta';
            app.MuestraDigitalMenu = uimenu(app.AnalisisMenu);
            app.MuestraDigitalMenu.Text = 'Muestra Digital';
            app.CrearReporteMenu = uimenu(app.AnalisisMenu);
            app.CrearReporteMenu.Text = 'Crear Reporte';
            app.FiltrosMenu = uimenu(app.AppView);
            app.FiltrosMenu.Text = 'Filtros';
            app.LowpassMenu = uimenu(app.FiltrosMenu);
            app.LowpassMenu.MenuSelectedFcn = createCallbackFcn(app, @LowpassMenuSelected, true);
            app.LowpassMenu.Text = 'Low-pass';
            app.HighpassMenu = uimenu(app.FiltrosMenu);
            app.HighpassMenu.MenuSelectedFcn = createCallbackFcn(app, @HighpassMenuSelected, true);
            app.HighpassMenu.Text = 'High-pass';
            app.BandpassMenu = uimenu(app.FiltrosMenu);
            app.BandpassMenu.MenuSelectedFcn = createCallbackFcn(app, @BandpassMenuSelected, true);
            app.BandpassMenu.Text = 'Band-pass';
            app.BandstopMenu = uimenu(app.FiltrosMenu);
            app.BandstopMenu.Text = 'Band-stop';
            app.GridLayout = uigridlayout(app.AppView);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.SealyAnalisisTab = uitab(app.TabGroup);
            app.SealyAnalisisTab.Title = 'Señal y Analisis';
            app.GridLayout13 = uigridlayout(app.SealyAnalisisTab);
            app.ModificarSealPanel = uipanel(app.GridLayout13);
            app.ModificarSealPanel.Title = 'Modificar Señal';
            app.GridLayout14_5 = uigridlayout(app.ModificarSealPanel);
            app.MultiplicadordeAmplitudEditFieldLabel = uilabel(app.GridLayout14_5);
            app.MultiplicadordeAmplitudEditFieldLabel.Text = 'Multiplicador de Amplitud';
            app.MultiplicadordeAmplitudEditField = uieditfield(app.GridLayout14_5, 'numeric');
            app.MultiplicadordeAmplitudEditField.Value = 1;
            app.ResetAmplifyBtn = uibutton(app.GridLayout14_5, 'push');
            app.ResetAmplifyBtn.ButtonPushedFcn = createCallbackFcn(app, @ResetAmplifyBtnButtonPushed, true);
            app.ResetAmplifyBtn.Text = 'Restablecer';
            app.ApplyAmplifyBtn = uibutton(app.GridLayout14_5, 'push');
            app.ApplyAmplifyBtn.ButtonPushedFcn = createCallbackFcn(app, @ApplyAmplifyBtnPushed, true);
            app.ApplyAmplifyBtn.Text = 'Aplicar';
            app.MultiplicadordefrecuenciaEditFieldLabel = uilabel(app.GridLayout14_5);
            app.MultiplicadordefrecuenciaEditFieldLabel.Text = 'Multiplicador de frecuencia';
            app.MultiplicadordefrecuenciaEditField = uieditfield(app.GridLayout14_5, 'numeric');
            app.MultiplicadordefrecuenciaEditField.Value = 1;
            app.LimitedeCorteInferioryEditFieldLabel = uilabel(app.GridLayout14_5);
            app.LimitedeCorteInferioryEditFieldLabel.Text = 'Limite de Corte Inferior (y)';
            app.LimitedeCorteInferioryEditField = uieditfield(app.GridLayout14_5, 'numeric');
            app.LimitedeCorteInferioryEditField.Value = -Inf;
            app.LimitedeCorteIzquierdoxEditFieldLabel = uilabel(app.GridLayout14_5);
            app.LimitedeCorteIzquierdoxEditFieldLabel.Text = 'Limite de Corte Izquierdo (x)';
            app.LimitedeCorteIzquierdoxEditField = uieditfield(app.GridLayout14_5, 'numeric');
            app.LimitedeCorteIzquierdoxEditField.Value = -Inf;
            app.LimitedeCorteDerechoxEditFieldLabel = uilabel(app.GridLayout14_5);
            app.LimitedeCorteDerechoxEditFieldLabel.Text = 'Limite de Corte Derecho(x)';
            app.LimitedeCorteDerechoxEditField = uieditfield(app.GridLayout14_5, 'numeric');
            app.LimitedeCorteDerechoxEditField.Value = Inf;
            app.LimitedeCorteSuperioryEditFieldLabel = uilabel(app.GridLayout14_5);
            app.LimitedeCorteSuperioryEditFieldLabel.Text = 'Limite de Corte Superior (y)';
            app.LimitedeCorteSuperioryEditField = uieditfield(app.GridLayout14_5, 'numeric');
            app.LimitedeCorteSuperioryEditField.Value = Inf;
            app.AnalisisdeFourierPanel = uipanel(app.GridLayout13);
            app.AnalisisdeFourierPanel.Title = 'Analisis de Fourier';
            app.GridLayout14_6 = uigridlayout(app.AnalisisdeFourierPanel);
            app.ResetFourierBtn = uibutton(app.GridLayout14_6, 'push');
            app.ResetFourierBtn.ButtonPushedFcn = createCallbackFcn(app, @ResetFourierBtnButtonPushed, true);
            app.ResetFourierBtn.Text = 'Restablecer';
            app.ApplyFourierBtn = uibutton(app.GridLayout14_6, 'push');
            app.ApplyFourierBtn.ButtonPushedFcn = createCallbackFcn(app, @ApplyFourierBtnButtonPushed, true);
            app.ApplyFourierBtn.Text = 'Aplicar';
            app.AplicaranalisisdeFourierCheckBox = uicheckbox(app.GridLayout14_6);
            app.AplicaranalisisdeFourierCheckBox.Text = 'Aplicar analisis de Fourier';
            app.AnalisisDiscretoPanel = uipanel(app.GridLayout13);
            app.AnalisisDiscretoPanel.Title = 'Analisis Discreto';
            app.GridLayout14_7 = uigridlayout(app.AnalisisDiscretoPanel);
            app.FrecuenciadeMuestreoEditFieldLabel = uilabel(app.GridLayout14_7);
            app.FrecuenciadeMuestreoEditFieldLabel.Text = 'Frecuencia de Muestreo';
            app.FrecuenciadeMuestreoEditField = uieditfield(app.GridLayout14_7, 'numeric');
            app.RestablecerButton_13 = uibutton(app.GridLayout14_7, 'push');
            app.RestablecerButton_13.Text = 'Restablecer';
            app.AplicarButton_13 = uibutton(app.GridLayout14_7, 'push');
            app.AplicarButton_13.Text = 'Aplicar';
            app.AplicaranalisisDiscretoCheckBox = uicheckbox(app.GridLayout14_7);
            app.AplicaranalisisDiscretoCheckBox.Text = 'Aplicar analisis Discreto';
            app.InformacindelasealPanel = uipanel(app.GridLayout13);
            app.InformacindelasealPanel.Title = 'Información de la señal';
            app.GridLayout14_9 = uigridlayout(app.InformacindelasealPanel);
            app.NombredeLaSealLabel = uilabel(app.GridLayout14_9);
            app.NombredeLaSealLabel.Text = 'Nombre de La Señal';
            app.SignalNameLabel = uilabel(app.GridLayout14_9);
            app.SignalNameLabel.Text = 'Audio';
            app.FrecuenciadeMuestreoLabel = uilabel(app.GridLayout14_9);
            app.FrecuenciadeMuestreoLabel.Text = 'Frecuencia de Muestreo';
            app.SignalFrecuencyLabel = uilabel(app.GridLayout14_9);
            app.SignalFrecuencyLabel.Text = '0Hz';
            app.DuracinLabel = uilabel(app.GridLayout14_9);
            app.DuracinLabel.Text = 'Duración';
            app.SignalDurationLabel = uilabel(app.GridLayout14_9);
            app.SignalDurationLabel.Text = '0s';
            app.ReproducirButton = uibutton(app.GridLayout14_9, 'push');
            app.ReproducirButton.Text = 'Reproducir';
            app.GraficasTab = uitab(app.TabGroup);
            app.GraficasTab.Title = 'Graficas';
            app.GridLayout3 = uigridlayout(app.GraficasTab);
            app.MuestraOriginalPanel = uipanel(app.GridLayout3);
            app.MuestraOriginalPanel.Title = 'Muestra Original';
            app.GridLayout4 = uigridlayout(app.MuestraOriginalPanel);
            app.UIAxesOriginalSignal = uiaxes(app.GridLayout4);
            title(app.UIAxesOriginalSignal, 'Señal')
            xlabel(app.UIAxesOriginalSignal, 'Tiempo (t)')
            ylabel(app.UIAxesOriginalSignal, 'Amplitud')
            app.UIAxesOriginalFourier = uiaxes(app.GridLayout4);
            title(app.UIAxesOriginalFourier, 'Analisis de Fourier')
            xlabel(app.UIAxesOriginalFourier, 'Frecuencia (F)')
            ylabel(app.UIAxesOriginalFourier, 'Amplitud')
            app.UIAxesOriginalDiscreet = uiaxes(app.GridLayout4);
            title(app.UIAxesOriginalDiscreet, 'Muestra Discreta')
            xlabel(app.UIAxesOriginalDiscreet, 'Tiempo Discreto [t]')
            ylabel(app.UIAxesOriginalDiscreet, 'Amplitud')
            app.OpcionesSealPanel = uipanel(app.GridLayout4);
            app.OpcionesSealPanel.Title = 'Opciones (Señal)';
            app.GridLayout9 = uigridlayout(app.OpcionesSealPanel);
            app.LimiteSuperioryEditFieldLabel = uilabel(app.GridLayout9);
            app.LimiteSuperioryEditFieldLabel.Text = 'Limite Superior (y)';
            app.LimiteSuperioryEditField = uieditfield(app.GridLayout9, 'numeric');
            app.LimiteInferioryEditFieldLabel = uilabel(app.GridLayout9);
            app.LimiteInferioryEditFieldLabel.Text = 'Limite Inferior (y)';
            app.LimiteInferioryEditField = uieditfield(app.GridLayout9, 'numeric');
            app.LimiteIzquierdoxEditFieldLabel = uilabel(app.GridLayout9);
            app.LimiteIzquierdoxEditFieldLabel.Text = 'Limite Izquierdo (x)';
            app.LimiteIzquierdoxEditField = uieditfield(app.GridLayout9, 'numeric');
            app.LimiteDerechoxEditFieldLabel = uilabel(app.GridLayout9);
            app.LimiteDerechoxEditFieldLabel.Text = 'Limite Derecho (x)';
            app.LimiteDerechoxEditField = uieditfield(app.GridLayout9, 'numeric');
            app.XGridCheckBox = uicheckbox(app.GridLayout9);
            app.XGridCheckBox.Text = 'X Grid';
            app.YGridCheckBox = uicheckbox(app.GridLayout9);
            app.YGridCheckBox.Text = 'Y Grid';
            app.AplicarButton_5 = uibutton(app.GridLayout9, 'push');
            app.AplicarButton_5.Text = 'Aplicar';
            app.RestablecerButton_5 = uibutton(app.GridLayout9, 'push');
            app.RestablecerButton_5.Text = 'Restablecer';
            app.OpcionesFourierPanel = uipanel(app.GridLayout4);
            app.OpcionesFourierPanel.Title = 'Opciones (Fourier)';
            app.GridLayout9_12 = uigridlayout(app.OpcionesFourierPanel);
            app.LimiteSuperioryEditField_7Label = uilabel(app.GridLayout9_12);
            app.LimiteSuperioryEditField_7Label.Text = 'Limite Superior (y)';
            app.LimiteSuperioryEditField_7 = uieditfield(app.GridLayout9_12, 'numeric');
            app.LimiteInferioryEditField_7Label = uilabel(app.GridLayout9_12);
            app.LimiteInferioryEditField_7Label.Text = 'Limite Inferior (y)';
            app.LimiteInferioryEditField_7 = uieditfield(app.GridLayout9_12, 'numeric');
            app.LimiteIzquierdoxEditField_7Label = uilabel(app.GridLayout9_12);
            app.LimiteIzquierdoxEditField_7Label.Text = 'Limite Izquierdo (x)';
            app.LimiteIzquierdoxEditField_7 = uieditfield(app.GridLayout9_12, 'numeric');
            app.LimiteDerechoxEditField_7Label = uilabel(app.GridLayout9_12);
            app.LimiteDerechoxEditField_7Label.Text = 'Limite Derecho (x)';
            app.LimiteDerechoxEditField_7 = uieditfield(app.GridLayout9_12, 'numeric');
            app.XGridCheckBox_7 = uicheckbox(app.GridLayout9_12);
            app.XGridCheckBox_7.Text = 'X Grid';
            app.YGridCheckBox_7 = uicheckbox(app.GridLayout9_12);
            app.YGridCheckBox_7.Text = 'Y Grid';
            app.AplicarButton_6 = uibutton(app.GridLayout9_12, 'push');
            app.AplicarButton_6.Text = 'Aplicar';
            app.RestablecerButton_6 = uibutton(app.GridLayout9_12, 'push');
            app.RestablecerButton_6.Text = 'Restablecer';
            app.OpcionesMuestraDiscretaPanel = uipanel(app.GridLayout4);
            app.OpcionesMuestraDiscretaPanel.Title = 'Opciones (Muestra Discreta)';
            app.GridLayout9_13 = uigridlayout(app.OpcionesMuestraDiscretaPanel);
            app.LimiteSuperioryEditField_8Label = uilabel(app.GridLayout9_13);
            app.LimiteSuperioryEditField_8Label.Text = 'Limite Superior (y)';
            app.LimiteSuperioryEditField_8 = uieditfield(app.GridLayout9_13, 'numeric');
            app.LimiteInferioryEditField_8Label = uilabel(app.GridLayout9_13);
            app.LimiteInferioryEditField_8Label.Text = 'Limite Inferior (y)';
            app.LimiteInferioryEditField_8 = uieditfield(app.GridLayout9_13, 'numeric');
            app.LimiteIzquierdoxEditField_8Label = uilabel(app.GridLayout9_13);
            app.LimiteIzquierdoxEditField_8Label.Text = 'Limite Izquierdo (x)';
            app.LimiteIzquierdoxEditField_8 = uieditfield(app.GridLayout9_13, 'numeric');
            app.LimiteDerechoxEditField_8Label = uilabel(app.GridLayout9_13);
            app.LimiteDerechoxEditField_8Label.Text = 'Limite Derecho (x)';
            app.LimiteDerechoxEditField_8 = uieditfield(app.GridLayout9_13, 'numeric');
            app.XGridCheckBox_8 = uicheckbox(app.GridLayout9_13);
            app.XGridCheckBox_8.Text = 'X Grid';
            app.YGridCheckBox_8 = uicheckbox(app.GridLayout9_13);
            app.YGridCheckBox_8.Text = 'Y Grid';
            app.AplicarButton_7 = uibutton(app.GridLayout9_13, 'push');
            app.AplicarButton_7.Text = 'Aplicar';
            app.RestablecerButton_7 = uibutton(app.GridLayout9_13, 'push');
            app.RestablecerButton_7.Text = 'Restablecer';
            app.MuestraModificadaPanel = uipanel(app.GridLayout3);
            app.MuestraModificadaPanel.Title = 'Muestra Modificada';
            app.GridLayout5 = uigridlayout(app.MuestraModificadaPanel);
            app.UIAxesModifiedDiscreet = uiaxes(app.GridLayout5);
            title(app.UIAxesModifiedDiscreet, 'Muestra Discreta')
            xlabel(app.UIAxesModifiedDiscreet, 'Tiempo Discreto [t]')
            ylabel(app.UIAxesModifiedDiscreet, 'Amplitud')
            app.UIAxesModifiedFourier = uiaxes(app.GridLayout5);
            title(app.UIAxesModifiedFourier, 'Analisis de Fourier')
            xlabel(app.UIAxesModifiedFourier, 'Amplitud')
            ylabel(app.UIAxesModifiedFourier, 'Amplitud')
            app.UIAxesModifiedSignal = uiaxes(app.GridLayout5);
            title(app.UIAxesModifiedSignal, 'Señal')
            xlabel(app.UIAxesModifiedSignal, 'Tiempo (t)')
            ylabel(app.UIAxesModifiedSignal, 'Amplitud')
            app.OpcionesSealPanel_4 = uipanel(app.GridLayout5);
            app.OpcionesSealPanel_4.Title = 'Opciones (Señal)';
            app.GridLayout9_14 = uigridlayout(app.OpcionesSealPanel_4);
            app.LimiteSuperioryEditField_9Label = uilabel(app.GridLayout9_14);
            app.LimiteSuperioryEditField_9Label.Text = 'Limite Superior (y)';
            app.LimiteSuperioryEditField_9 = uieditfield(app.GridLayout9_14, 'numeric');
            app.LimiteInferioryEditField_9Label = uilabel(app.GridLayout9_14);
            app.LimiteInferioryEditField_9Label.Text = 'Limite Inferior (y)';
            app.LimiteInferioryEditField_9 = uieditfield(app.GridLayout9_14, 'numeric');
            app.LimiteIzquierdoxEditField_9Label = uilabel(app.GridLayout9_14);
            app.LimiteIzquierdoxEditField_9Label.Text = 'Limite Izquierdo (x)';
            app.LimiteIzquierdoxEditField_9 = uieditfield(app.GridLayout9_14, 'numeric');
            app.LimiteDerechoxEditField_9Label = uilabel(app.GridLayout9_14);
            app.LimiteDerechoxEditField_9Label.Text = 'Limite Derecho (x)';
            app.LimiteDerechoxEditField_9 = uieditfield(app.GridLayout9_14, 'numeric');
            app.XGridCheckBox_9 = uicheckbox(app.GridLayout9_14);
            app.XGridCheckBox_9.Text = 'X Grid';
            app.YGridCheckBox_9 = uicheckbox(app.GridLayout9_14);
            app.YGridCheckBox_9.Text = 'Y Grid';
            app.AplicarButton_8 = uibutton(app.GridLayout9_14, 'push');
            app.AplicarButton_8.Text = 'Aplicar';
            app.RestablecerButton_8 = uibutton(app.GridLayout9_14, 'push');
            app.RestablecerButton_8.Text = 'Restablecer';
            app.OpcionesFourierPanel_2 = uipanel(app.GridLayout5);
            app.OpcionesFourierPanel_2.Title = 'Opciones (Fourier)';
            app.GridLayout9_15 = uigridlayout(app.OpcionesFourierPanel_2);
            app.LimiteSuperioryEditField_10Label = uilabel(app.GridLayout9_15);
            app.LimiteSuperioryEditField_10Label.Text = 'Limite Superior (y)';
            app.LimiteSuperioryEditField_10 = uieditfield(app.GridLayout9_15, 'numeric');
            app.LimiteInferioryEditField_10Label = uilabel(app.GridLayout9_15);
            app.LimiteInferioryEditField_10Label.Text = 'Limite Inferior (y)';
            app.LimiteInferioryEditField_10 = uieditfield(app.GridLayout9_15, 'numeric');
            app.LimiteIzquierdoxEditField_10Label = uilabel(app.GridLayout9_15);
            app.LimiteIzquierdoxEditField_10Label.Text = 'Limite Izquierdo (x)';
            app.LimiteIzquierdoxEditField_10 = uieditfield(app.GridLayout9_15, 'numeric');
            app.LimiteDerechoxEditField_10Label = uilabel(app.GridLayout9_15);
            app.LimiteDerechoxEditField_10Label.Text = 'Limite Derecho (x)';
            app.LimiteDerechoxEditField_10 = uieditfield(app.GridLayout9_15, 'numeric');
            app.XGridCheckBox_10 = uicheckbox(app.GridLayout9_15);
            app.XGridCheckBox_10.Text = 'X Grid';
            app.YGridCheckBox_10 = uicheckbox(app.GridLayout9_15);
            app.YGridCheckBox_10.Text = 'Y Grid';
            app.AplicarButton_9 = uibutton(app.GridLayout9_15, 'push');
            app.AplicarButton_9.Text = 'Aplicar';
            app.RestablecerButton_9 = uibutton(app.GridLayout9_15, 'push');
            app.RestablecerButton_9.Text = 'Restablecer';
            app.OpcionesMuestraDiscretaPanel_2 = uipanel(app.GridLayout5);
            app.OpcionesMuestraDiscretaPanel_2.Title = 'Opciones (Muestra Discreta)';
            app.GridLayout9_16 = uigridlayout(app.OpcionesMuestraDiscretaPanel_2);
            app.LimiteSuperioryEditField_11Label = uilabel(app.GridLayout9_16);
            app.LimiteSuperioryEditField_11Label.Text = 'Limite Superior (y)';
            app.LimiteSuperioryEditField_11 = uieditfield(app.GridLayout9_16, 'numeric');
            app.LimiteInferioryEditField_11Label = uilabel(app.GridLayout9_16);
            app.LimiteInferioryEditField_11Label.Text = 'Limite Inferior (y)';
            app.LimiteInferioryEditField_11 = uieditfield(app.GridLayout9_16, 'numeric');
            app.LimiteIzquierdoxEditField_11Label = uilabel(app.GridLayout9_16);
            app.LimiteIzquierdoxEditField_11Label.Text = 'Limite Izquierdo (x)';
            app.LimiteIzquierdoxEditField_11 = uieditfield(app.GridLayout9_16, 'numeric');
            app.LimiteDerechoxEditField_11Label = uilabel(app.GridLayout9_16);
            app.LimiteDerechoxEditField_11Label.Text = 'Limite Derecho (x)';
            app.LimiteDerechoxEditField_11 = uieditfield(app.GridLayout9_16, 'numeric');
            app.XGridCheckBox_11 = uicheckbox(app.GridLayout9_16);
            app.XGridCheckBox_11.Text = 'X Grid';
            app.YGridCheckBox_11 = uicheckbox(app.GridLayout9_16);
            app.YGridCheckBox_11.Text = 'Y Grid';
            app.AplicarButton_10 = uibutton(app.GridLayout9_16, 'push');
            app.AplicarButton_10.Text = 'Aplicar';
            app.RestablecerButton_10 = uibutton(app.GridLayout9_16, 'push');
            app.RestablecerButton_10.Text = 'Restablecer';
            app.FiltrosTab_2 = uitab(app.TabGroup);
            app.FiltrosTab_2.Title = 'Filtros';
            app.GridLayout12 = uigridlayout(app.FiltrosTab_2);
            app.LowPassPanel = uipanel(app.GridLayout12);
            app.LowPassPanel.Title = 'Low-Pass';
            app.GridLayout14 = uigridlayout(app.LowPassPanel);
            app.LimiteInferiorEditFieldLabel = uilabel(app.GridLayout14);
            app.LimiteInferiorEditFieldLabel.Text = 'Limite Inferior';
            app.LimiteInferiorLowPassEditField = uieditfield(app.GridLayout14, 'numeric');
            app.AplicarLowPassCheckBox = uicheckbox(app.GridLayout14);
            app.AplicarLowPassCheckBox.Text = 'Aplicar filtro';
            app.RestablecerLowPassBtn = uibutton(app.GridLayout14, 'push');
            app.RestablecerLowPassBtn.Text = 'Restablecer';
            app.AplicarLowPassBtn = uibutton(app.GridLayout14, 'push');
            app.AplicarLowPassBtn.Text = 'Aplicar';
            app.HighPassPanel = uipanel(app.GridLayout12);
            app.HighPassPanel.Title = 'High-Pass';
            app.GridLayout14_2 = uigridlayout(app.HighPassPanel);
            app.LimiteSuperiorEditFieldLabel = uilabel(app.GridLayout14_2);
            app.LimiteSuperiorEditFieldLabel.Text = 'Limite Superior';
            app.LimiteSuperiorHighPassEditField = uieditfield(app.GridLayout14_2, 'numeric');
            app.AplicarHighPassCheckBox = uicheckbox(app.GridLayout14_2);
            app.AplicarHighPassCheckBox.Text = 'Aplicar filtro';
            app.RestablecerHighPassBtn = uibutton(app.GridLayout14_2, 'push');
            app.RestablecerHighPassBtn.Text = 'Restablecer';
            app.AplicarHighPassBtn = uibutton(app.GridLayout14_2, 'push');
            app.AplicarHighPassBtn.Text = 'Aplicar';
            app.BandPassPanel = uipanel(app.GridLayout12);
            app.BandPassPanel.Title = 'Band-Pass';
            app.GridLayout14_3 = uigridlayout(app.BandPassPanel);
            app.LimiteInferiorEditField_2Label = uilabel(app.GridLayout14_3);
            app.LimiteInferiorEditField_2Label.Text = 'Limite Inferior';
            app.LimiteInferiorEditField_2 = uieditfield(app.GridLayout14_3, 'numeric');
            app.AplicarfiltroCheckBox_3 = uicheckbox(app.GridLayout14_3);
            app.AplicarfiltroCheckBox_3.Text = 'Aplicar filtro';
            app.RestablecerButton_3 = uibutton(app.GridLayout14_3, 'push');
            app.RestablecerButton_3.Text = 'Restablecer';
            app.AplicarButton_3 = uibutton(app.GridLayout14_3, 'push');
            app.AplicarButton_3.Text = 'Aplicar';
            app.LimiteSuperiorEditField_2Label = uilabel(app.GridLayout14_3);
            app.LimiteSuperiorEditField_2Label.Text = 'Limite Superior';
            app.LimiteSuperiorEditField_2 = uieditfield(app.GridLayout14_3, 'numeric');
            app.BandStopPanel = uipanel(app.GridLayout12);
            app.BandStopPanel.Title = 'Band-Stop';
            app.GridLayout14_4 = uigridlayout(app.BandStopPanel);
            app.LimiteInferiorEditField_3Label = uilabel(app.GridLayout14_4);
            app.LimiteInferiorEditField_3Label.Text = 'Limite Inferior';
            app.LimiteInferiorEditField_3 = uieditfield(app.GridLayout14_4, 'numeric');
            app.AplicarfiltroCheckBox_4 = uicheckbox(app.GridLayout14_4);
            app.AplicarfiltroCheckBox_4.Text = 'Aplicar filtro';
            app.RestablecerButton_4 = uibutton(app.GridLayout14_4, 'push');
            app.RestablecerButton_4.Text = 'Restablecer';
            app.AplicarButton_4 = uibutton(app.GridLayout14_4, 'push');
            app.AplicarButton_4.Text = 'Aplicar';
            app.LimiteSuperiorEditField_3Label = uilabel(app.GridLayout14_4);
            app.LimiteSuperiorEditField_3Label.Text = 'Limite Superior';
            app.LimiteSuperiorEditField_3 = uieditfield(app.GridLayout14_4, 'numeric');
            app.CrearSealTab = uitab(app.TabGroup);
            app.CrearSealTab.Title = 'Crear Señal';
            app.GridLayout6 = uigridlayout(app.CrearSealTab);
            app.GrabarAudioPanel = uipanel(app.GridLayout6);
            app.GrabarAudioPanel.Title = 'Grabar Audio';
            app.GridLayout7 = uigridlayout(app.GrabarAudioPanel);
            app.UIAxes7 = uiaxes(app.GridLayout7);
            title(app.UIAxes7, 'Title')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            app.OpcionesGrabacionPanel = uipanel(app.GridLayout7);
            app.OpcionesGrabacionPanel.Title = 'Opciones (Grabacion)';
            app.GridLayout10 = uigridlayout(app.OpcionesGrabacionPanel);
            app.CrearRegistroButton = uibutton(app.GridLayout10, 'push');
            app.CrearRegistroButton.Text = 'Crear Registro';
            app.DuracinEditFieldLabel = uilabel(app.GridLayout10);
            app.DuracinEditFieldLabel.Text = 'Duración';
            app.DuracinEditField = uieditfield(app.GridLayout10, 'numeric');
            app.NombreEditFieldLabel = uilabel(app.GridLayout10);
            app.NombreEditFieldLabel.Text = 'Nombre';
            app.NombreEditField = uieditfield(app.GridLayout10, 'text');
            app.NombreEditField.Value = 'signal';
            app.IniciarGrabacinButton = uibutton(app.GridLayout10, 'push');
            app.IniciarGrabacinButton.Text = 'Iniciar Grabación';
            app.GrabandoLabel = uilabel(app.GridLayout10);
            app.GrabandoLabel.Text = 'Grabando';
            app.GrabandoLamp = uilamp(app.GridLayout10);
            app.NombreAutomaticoCheckBox = uicheckbox(app.GridLayout10);
            app.NombreAutomaticoCheckBox.Text = 'Nombre Automatico';
            app.SealSinteticaPanel = uipanel(app.GridLayout6);
            app.SealSinteticaPanel.Title = 'Señal Sintetica';
            app.GridLayout8 = uigridlayout(app.SealSinteticaPanel);
            app.UIAxes8 = uiaxes(app.GridLayout8);
            title(app.UIAxes8, 'Title')
            xlabel(app.UIAxes8, 'X')
            ylabel(app.UIAxes8, 'Y')
            app.OpcionesSealPanel_5 = uipanel(app.GridLayout8);
            app.OpcionesSealPanel_5.Title = 'Opciones (Señal)';
            app.GridLayout11 = uigridlayout(app.OpcionesSealPanel_5);
            app.CrearRegistroButton_2 = uibutton(app.GridLayout11, 'push');
            app.CrearRegistroButton_2.Text = 'Crear Registro';
            app.TipodesealDropDownLabel = uilabel(app.GridLayout11);
            app.TipodesealDropDownLabel.Text = 'Tipo de señal';
            app.TipodesealDropDown = uidropdown(app.GridLayout11);
            app.FaseEditFieldLabel = uilabel(app.GridLayout11);
            app.FaseEditFieldLabel.Text = 'Fase';
            app.FaseEditField = uieditfield(app.GridLayout11, 'numeric');
            app.FrecuenciaEditFieldLabel = uilabel(app.GridLayout11);
            app.FrecuenciaEditFieldLabel.Text = 'Frecuencia';
            app.FrecuenciaEditField = uieditfield(app.GridLayout11, 'numeric');
            app.AmplitudEditFieldLabel = uilabel(app.GridLayout11);
            app.AmplitudEditFieldLabel.Text = 'Amplitud';
            app.AmplitudEditField = uieditfield(app.GridLayout11, 'numeric');
            app.DuracinEditField_2Label = uilabel(app.GridLayout11);
            app.DuracinEditField_2Label.Text = 'Duración';
            app.DuracinEditField_2 = uieditfield(app.GridLayout11, 'numeric');
            app.NombreEditField_2Label = uilabel(app.GridLayout11);
            app.NombreEditField_2Label.Text = 'Nombre';
            app.NombreEditField_2 = uieditfield(app.GridLayout11, 'text');
            app.NombreEditField_2.Value = 'signal';
            app.NombreAutomaticoCheckBox_2 = uicheckbox(app.GridLayout11);
            app.NombreAutomaticoCheckBox_2.Text = 'Nombre Automatico';
            app.FuncionPersonalizadaCheckBox = uicheckbox(app.GridLayout11);
            app.FuncionPersonalizadaCheckBox.Text = 'Funcion Personalizada';
            app.FuncinTextAreaLabel = uilabel(app.GridLayout11);
            app.FuncinTextAreaLabel.Enable = 'off';
            app.FuncinTextAreaLabel.Text = 'Función';
            app.FuncinTextArea = uitextarea(app.GridLayout11);
            app.FuncinTextArea.Enable = 'off';
            app.FuncinTextArea.Value = {'sin(pi * 2 * x) + cos(pi * 4 * x)'};
            app.GraficarButton = uibutton(app.GridLayout11, 'push');
            app.GraficarButton.Text = 'Graficar';
            app.LimpiarButton = uibutton(app.GridLayout11, 'push');
            app.LimpiarButton.Text = 'Limpiar';
            app.Operacionescon2SealesTab = uitab(app.TabGroup);
            app.Operacionescon2SealesTab.Title = 'Operaciones con 2 Señales';
            app.GridLayout18 = uigridlayout(app.Operacionescon2SealesTab);
            app.OpcionesOperacionesPanel = uipanel(app.GridLayout18);
            app.OpcionesOperacionesPanel.Title = 'Opciones (Operaciones)';
            app.GridLayout19 = uigridlayout(app.OpcionesOperacionesPanel);
            app.Registro1DropDownLabel = uilabel(app.GridLayout19);
            app.Registro1DropDownLabel.Text = 'Registro 1';
            app.Registro1DropDown = uidropdown(app.GridLayout19);
            app.Registro1DropDown.Items = {'', ''};
            app.Registro1DropDown.Value = '';
            app.Registro2DropDownLabel = uilabel(app.GridLayout19);
            app.Registro2DropDownLabel.Text = 'Registro 2';
            app.Registro2DropDown = uidropdown(app.GridLayout19);
            app.Registro2DropDown.Items = {'', ''};
            app.Registro2DropDown.Value = '';
            app.OperacinDropDownLabel = uilabel(app.GridLayout19);
            app.OperacinDropDownLabel.Text = 'Operación';
            app.OperacinDropDown = uidropdown(app.GridLayout19);
            app.OperacinDropDown.Items = {'Sumar', 'Restar', 'Multiplicar', 'Dividir', 'Convolucion', 'Sobreponer'};
            app.OperacinDropDown.Value = 'Sumar';
            app.AplicarButton_16 = uibutton(app.GridLayout19, 'push');
            app.AplicarButton_16.Text = 'Aplicar';
            app.CrearRegistroButton_3 = uibutton(app.GridLayout19, 'push');
            app.CrearRegistroButton_3.Text = 'Crear Registro';
            app.Who1DropDown = uidropdown(app.GridLayout19);
            app.Who1DropDown.Items = {'Original', 'Modificada'};
            app.Who1DropDown.Value = 'Original';
            app.Who2DropDown = uidropdown(app.GridLayout19);
            app.Who2DropDown.Items = {'Original', 'Modificada'};
            app.Who2DropDown.Value = 'Original';
            app.UIAxes9 = uiaxes(app.GridLayout18);
            title(app.UIAxes9, 'Title')
            xlabel(app.UIAxes9, 'X')
            ylabel(app.UIAxes9, 'Y')
            app.OpcionesGraficaPanel = uipanel(app.GridLayout18);
            app.OpcionesGraficaPanel.Title = 'Opciones (Grafica)';
            app.GridLayout9_17 = uigridlayout(app.OpcionesGraficaPanel);
            app.LimiteSuperioryEditField_12Label = uilabel(app.GridLayout9_17);
            app.LimiteSuperioryEditField_12Label.Text = 'Limite Superior (y)';
            app.LimiteSuperioryEditField_12 = uieditfield(app.GridLayout9_17, 'numeric');
            app.LimiteInferioryEditField_12Label = uilabel(app.GridLayout9_17);
            app.LimiteInferioryEditField_12Label.Text = 'Limite Inferior (y)';
            app.LimiteInferioryEditField_12 = uieditfield(app.GridLayout9_17, 'numeric');
            app.LimiteIzquierdoxEditField_12Label = uilabel(app.GridLayout9_17);
            app.LimiteIzquierdoxEditField_12Label.Text = 'Limite Izquierdo (x)';
            app.LimiteIzquierdoxEditField_12 = uieditfield(app.GridLayout9_17, 'numeric');
            app.LimiteDerechoxEditField_12Label = uilabel(app.GridLayout9_17);
            app.LimiteDerechoxEditField_12Label.Text = 'Limite Derecho (x)';
            app.LimiteDerechoxEditField_12 = uieditfield(app.GridLayout9_17, 'numeric');
            app.XGridCheckBox_12 = uicheckbox(app.GridLayout9_17);
            app.XGridCheckBox_12.Text = 'X Grid';
            app.YGridCheckBox_12 = uicheckbox(app.GridLayout9_17);
            app.YGridCheckBox_12.Text = 'Y Grid';
            app.AplicarButton_15 = uibutton(app.GridLayout9_17, 'push');
            app.AplicarButton_15.Text = 'Aplicar';
            app.RestablecerButton_15 = uibutton(app.GridLayout9_17, 'push');
            app.RestablecerButton_15.Text = 'Restablecer';
            app.RegistrosdeSealPanel = uipanel(app.GridLayout);
            app.RegistrosdeSealPanel.Title = 'Registros de Señal';
            app.GridLayout2 = uigridlayout(app.RegistrosdeSealPanel);
            app.AgregarRegistroButton = uibutton(app.GridLayout2, 'push');
            app.AgregarRegistroButton.ButtonPushedFcn = createCallbackFcn(app, @AgregarRegistroButtonPushed, true);
            app.AgregarRegistroButton.Text = 'Agregar Registro';
            app.BorrarRegistroButton = uibutton(app.GridLayout2, 'push');
            app.BorrarRegistroButton.Text = 'Borrar Registro';
            app.ListBox = uilistbox(app.GridLayout2);
            app.ListBox.Items = {};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {};
            % Show the figure after all components are created
            app.AppView.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AppView)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AppView)
        end
    end
end