classdef FourierAnalisys < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AnalisisdeFourierUIFigure  matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        CancelarButton             matlab.ui.control.Button
        AplicarButton              matlab.ui.control.Button
        AplicaranalisisdeFourierCheckBox  matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        appcallback
        apply
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, appcallback, apply)
            app.appcallback = appcallback;
            app.apply = apply;
            
            app.AplicaranalisisdeFourierCheckBox.Value = apply;
        end

        % Button pushed function: CancelarButton
        function CancelarButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: AplicarButton
        function AplicarButtonPushed(app, event)
            app.apply = app.AplicaranalisisdeFourierCheckBox.Value;
            
            callbackFourierAnalisis(app.appcallback, app.apply)
            
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AnalisisdeFourierUIFigure = uifigure('Visible', 'off');
            app.AnalisisdeFourierUIFigure.Name = 'Analisis de Fourier';
            app.GridLayout = uigridlayout(app.AnalisisdeFourierUIFigure);
            app.CancelarButton = uibutton(app.GridLayout, 'push');
            app.CancelarButton.ButtonPushedFcn = createCallbackFcn(app, @CancelarButtonPushed, true);
            app.CancelarButton.Text = 'Cancelar';
            app.AplicarButton = uibutton(app.GridLayout, 'push');
            app.AplicarButton.ButtonPushedFcn = createCallbackFcn(app, @AplicarButtonPushed, true);
            app.AplicarButton.Text = 'Aplicar';
            app.AplicaranalisisdeFourierCheckBox = uicheckbox(app.GridLayout);
            app.AplicaranalisisdeFourierCheckBox.Text = 'Aplicar analisis de Fourier';
            % Show the figure after all components are created
            app.AnalisisdeFourierUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FourierAnalisys(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AnalisisdeFourierUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AnalisisdeFourierUIFigure)
        end
    end
end