classdef LowPass < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LowPassView                   matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LimiteInferiorEditFieldLabel  matlab.ui.control.Label
        LimiteInferiorEditField       matlab.ui.control.NumericEditField
        CancelarButton                matlab.ui.control.Button
        AplicarButton                 matlab.ui.control.Button
        AplicarfiltroCheckBox         matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        appcallback
        apply 
        lim
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, appcallback, apply, lim)
            app.appcallback = appcallback;
            app.apply = apply;
            app.lim = lim;
            
            app.AplicarfiltroCheckBox.Value = apply;
            app.LimiteInferiorEditField.Value = lim;
        end

        % Button pushed function: CancelarButton
        function CancelarButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: AplicarButton
        function AplicarButtonPushed(app, event)
            app.apply = app.AplicarfiltroCheckBox.Value;
            app.lim = app.LimiteInferiorEditField.Value;
            
            callbackLowPassFilter(app.appcallback, app.apply, app.lim);
            
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LowPassView = uifigure('Visible', 'off');
            app.LowPassView.Name = 'Filtro Low-pass';
            app.GridLayout = uigridlayout(app.LowPassView);
            app.LimiteInferiorEditFieldLabel = uilabel(app.GridLayout);
            app.LimiteInferiorEditFieldLabel.Text = 'Limite Inferior';
            app.LimiteInferiorEditField = uieditfield(app.GridLayout, 'numeric');
            app.CancelarButton = uibutton(app.GridLayout, 'push');
            app.CancelarButton.ButtonPushedFcn = createCallbackFcn(app, @CancelarButtonPushed, true);
            app.CancelarButton.Text = 'Cancelar';
            app.AplicarButton = uibutton(app.GridLayout, 'push');
            app.AplicarButton.ButtonPushedFcn = createCallbackFcn(app, @AplicarButtonPushed, true);
            app.AplicarButton.Text = 'Aplicar';
            app.AplicarfiltroCheckBox = uicheckbox(app.GridLayout);
            app.AplicarfiltroCheckBox.Text = 'Aplicar filtro';
            % Show the figure after all components are created
            app.LowPassView.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LowPass(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LowPassView)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LowPassView)
        end
    end
end